classdef EASY_FIA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        WaitingLabel                   matlab.ui.control.Label
        SortmatrixbyadductsCheckBox    matlab.ui.control.CheckBox
        HMDBANNOTATIONButton           matlab.ui.control.Button
        ALIGNMENTButton                matlab.ui.control.Button
        OutputFolderEditField          matlab.ui.control.EditField
        OutputFolderEditFieldLabel     matlab.ui.control.Label
        AdductdefinitionPanel          matlab.ui.container.Panel
        MKEditField                    matlab.ui.control.NumericEditField
        MKLabel                        matlab.ui.control.Label
        MNaEditField                   matlab.ui.control.NumericEditField
        MNaLabel                       matlab.ui.control.Label
        ParametersPanel                matlab.ui.container.Panel
        SamplecutoffEditField          matlab.ui.control.NumericEditField
        SamplecutoffEditFieldLabel     matlab.ui.control.Label
        IntensitycutoffEditField       matlab.ui.control.NumericEditField
        IntensitycutoffEditFieldLabel  matlab.ui.control.Label
        DeltamassppmEditField          matlab.ui.control.NumericEditField
        DeltamassppmEditFieldLabel     matlab.ui.control.Label
        EASYFIALabel                   matlab.ui.control.Label
    end


    properties (Access = private)
        ppm_value
        check
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            output_dir = strcat('Alignment-',date);
            app.OutputFolderEditField.Value = output_dir;
            app.DeltamassppmEditField.Value = 6;
            app.IntensitycutoffEditField.Value = 0;
            app.SamplecutoffEditField.Value = 1;
        end

        % Value changed function: SortmatrixbyadductsCheckBox
        function SortmatrixbyadductsCheckBoxValueChanged(app, event)
            app.check = app.SortmatrixbyadductsCheckBox.Value;
        end

        % Button pushed function: ALIGNMENTButton
        function ALIGNMENTButtonPushed(app, event)
            sample_dir = [];

            % Input data (to be transformed in variables)
            ris_dir = app.OutputFolderEditField.Value;
            app.ppm_value = app.DeltamassppmEditField.Value;
            cut_off = app.IntensitycutoffEditField.Value;
            app.check = app.SortmatrixbyadductsCheckBox.Value;

            % Adduct mass (taken from "Mass spectrometry adduct calculator")
            M_Na = app.MNaEditField.Value;
            M_K = app.MKEditField.Value;

            app.WaitingLabel.Text = 'Alignment section - START';
            
            % Align either the positive or the negative acquisition

            % Directory operations
            if isempty(sample_dir)
                sample_dir = uigetdir;
            end

            if ~isfolder(fullfile(sample_dir,ris_dir))
                mkdir(fullfile(sample_dir,ris_dir))
            end

            [~,end_path_1] = strtok(sample_dir,'\');
            [~,end_path_2] = strtok(end_path_1,'\');

            while ~isempty(end_path_2)
                end_path_1 = end_path_2;
                [~,end_path_2] = strtok(end_path_1,'\');
            end
            name_dir = end_path_1(2:end);
            [start_dir,end_dir] = strtok(name_dir,'_');
            
            if strcmpi(end_dir(2:end),'pos')|| strcmpi(end_dir(2:end),'neg')||strcmpi(start_dir,'pos')|| strcmpi(start_dir,'neg')

                % Definition of the scan mode (positive or negative)
                if strcmpi(end_dir(2:end),'pos')|| strcmpi(end_dir(2:end),'neg')
                    m_acq = end_dir(2:end);
                elseif strcmpi(start_dir,'pos')|| strcmpi(start_dir,'neg')
                    m_acq = start_dir;
                end
                
                % Change adducts value based on the scan mode 
                if strcmpi(m_acq,'pos')
                    name_output = [m_acq,'_',regexprep(num2str(date()),'-','')];
                    M_H = 1.007276;
                    tag_M_H = 'M+H';
                elseif strcmpi(m_acq,'neg')
                    name_output = [m_acq,'_',regexprep(num2str(date()),'-','')];
                    M_H  = -1.007276;
                    tag_M_H = 'M-H';
                end

                % Set the path for the result
                pathExtention = fullfile(sample_dir,'*csv');
                path_out = fullfile(sample_dir,ris_dir,name_output);
                % Allfile contains the names of the samples and wash spectra
                Allfile = dir(pathExtention);
                n_file = size(Allfile,1);

                % Creation of two tables
                wash_sub = cell(500000,n_file*2);
                ambi = cell(1000,n_file*4);
                disp(['type: ',m_acq])

                % Retrieve the name of the wash spectra
                if ~isempty(Allfile(strncmp('Wash_', {Allfile.name}, 1)))
                    wash='Wash_';
                elseif ~isempty(Allfile(strncmp('wash_', {Allfile.name}, 1)))
                    wash='wash_';
                elseif ~isempty(Allfile(strncmp('WASH_', {Allfile.name}, 1)))
                    wash='WASH_';
                elseif ~isempty(Allfile(strncmp('lav_', {Allfile.name}, 1)))
                    wash='lav_';
                elseif ~isempty(Allfile(strncmp('Lav_', {Allfile.name}, 1)))
                    wash='Lav_';
                elseif ~isempty(Allfile(strncmp('LAV_', {Allfile.name}, 1)))
                    wash='LAV_';
                end

                % Number of sample spectra
                N_sample = n_file-length(Allfile(strncmp(wash,{Allfile.name}, 1)));
                % Creation of a struct for the samples
                samples = struct ('SpectrumName', {},'ID', {}, 'MW', {}, 'Intensity', {});
                % Vector with a number of elements equal
                % to the number of sample spectra
                num_masses = zeros(N_sample,1);
                MWMassesArray = 0;
                IntensityArray = 0;
                IDArray = 0;
                nSample_Array = 0;
                s = 0;

                % Alignment between each sample and its wash
                for f=1:n_file

                    if ~strcmpi(Allfile(f).name(1:length(wash)),wash)

                        % Name of the sample file
                        name_file = Allfile(f).name;

                        s=s+1;
                        % Cell which contains the name of the sample file
                        name_sample{s} = strtok(name_file,'.');
                        % Name of the sample's wash
                        wash_sample = [wash,name_sample{s}];

                        % Fill the struct "samples"
                        samples(s).SpectrumName = name_sample{s};
                        % Sample and wash paths
                        name_path = fullfile(sample_dir,name_sample{s});
                        wash_path = fullfile(sample_dir,wash_sample);
                        % Sample and wash paths with their extension
                        name_path_csv = [name_path,'.csv'];
                        wash_path_csv = [wash_path,'.csv'];

                        sample_one_spettro = import_spectra_csv(name_path_csv);
                        wash_one_spettro = import_spectra_csv(wash_path_csv);
                        sample_one_spettro = sample_one_spettro(~isnan(sample_one_spettro.i),:);
                        wash_one_spettro = wash_one_spettro(~isnan(wash_one_spettro.i),:);

                        if size(sample_one_spettro,1)>=500000
                            app.WaitingLabel.Text = 'Sample dimension>5000!';
                        end
                        if size(wash_one_spettro,1)>=500000
                            app.WaitingLabel.Text = 'Blank dimension>5000!';
                        end

                        % WASH SUBTRACTION
                        % Fill the tables wash_sub and ambi
                        wash_sub(1,s*3) = name_sample(s);
                        wash_sub{2,(s-1)*4+1} = 'm/z_sample';
                        wash_sub{2,(s-1)*4+2} = 'int_sample';
                        wash_sub{2,(s-1)*4+3} = 'm/z_wash';
                        wash_sub{2,(s-1)*4+4} = 'int_wash';
                        ambi(1,s*3) = name_sample(s);
                        ambi{2,(s-1)*4+1} = 'm/z_sample';
                        ambi{2,(s-1)*4+2} = 'int_sample';
                        ambi{2,(s-1)*4+3} = 'm/z_wash';
                        ambi{2,(s-1)*4+4} = 'int_wash';
                        z = 3;
                        a = 3;

                        tic
                        for w=1:size(wash_one_spettro,1)

                            pos_b=find(sample_one_spettro.x>=(wash_one_spettro.x(w)-10^-6*app.ppm_value*wash_one_spettro.x(w))...
                                & sample_one_spettro.x<=(wash_one_spettro.x(w)+10^-6*app.ppm_value*wash_one_spettro.x(w)));

                            if ~isempty(pos_b)
                                % In case of ambiguity, keep the wash m/z
                                % value which is closest to the sample
                                % (e.g. with the lowest delta ppm)
                                if length(pos_b)>1
                                    ambi(z:z+length(pos_b)-1,(s-1)*4+1) = num2cell(sample_one_spettro.x(pos_b));
                                    ambi(z:z+length(pos_b)-1,(s-1)*4+2) = num2cell(sample_one_spettro.y(pos_b));
                                    ambi(z,(s-1)*4+3) = num2cell(wash_one_spettro.x(w));
                                    ambi(z,(s-1)*4+4) = num2cell(wash_one_spettro.y(w));
                                    z = z+length(pos_b);
                                    [~,pp] = min(abs((sample_one_spettro.x(pos_b)-wash_one_spettro.x(w)).*10^6./sample_one_spettro.x(pos_b)));
                                    pos_b = pos_b(pp);
                                end

                                wash_sub(a,(s-1)*4+1) = num2cell(sample_one_spettro.x(pos_b));
                                wash_sub(a,(s-1)*4+2) = num2cell(sample_one_spettro.y(pos_b));
                                wash_sub(a,(s-1)*4+3) = num2cell(wash_one_spettro.x(w));
                                wash_sub(a,(s-1)*4+4) = num2cell(wash_one_spettro.y(w));

                                % Subtraction of the wash spectrum in the
                                % sample_one_spettro table
                                sample_one_spettro.y(pos_b) = sample_one_spettro.y(pos_b)-wash_one_spettro.y(w);
                                a = a+1;
                            end
                        end
                        toc

                        sy = find(sample_one_spettro.y>=cut_off);
                        % Number of masses over the cutoff intensity
                        % NB: For our application, the cutoff is set to ZERO
                        % because we don't want to discard the "noise"
                        num_masses(f) = size(sy,1);
                        samples(s).ID = sample_one_spettro.i(sy);
                        samples(s).MW = sample_one_spettro.x(sy);
                        samples(s).Intensity = sample_one_spettro.y(sy);

                        % Put together all the samples information
                        % into a unique array
                        MWMassesArray = cat(1,MWMassesArray,samples(s).MW);
                        IntensityArray = cat(1,IntensityArray,samples(s).Intensity);
                        IDArray = cat(1,IDArray,samples(s).ID);
                        % Array which contains for each cell
                        % the number of sample to which it refers
                        nSample_Array = cat(1,nSample_Array,repmat(f,num_masses(s),1));
                    end
                end

                % Save wash_sub and clear the variables
                % writecell(wash_sub, strcat(path_out,'_samples+blank.xlsx'));
                clear wash_sub;
                clear ambi;

                % Discard the first row (=0)
                MWMassesArray(1) = [];
                IntensityArray(1) = [];
                IDArray(1) = [];
                nSample_Array(1) = [];
                MassesMean = 0;

                % Sort the vectors containing all the samples
                [MWMassesArray, ord_vet] = sort (MWMassesArray, 'ascend');
                IntensityArray = IntensityArray(ord_vet);
                IDArray = IDArray(ord_vet);
                nSample_Array = nSample_Array(ord_vet);

                assignin('base', 'MWMassesArray', MWMassesArray);
                assignin('base', 'IntensityArray', IntensityArray);
                assignin('base', 'IDArray',IDArray );
                assignin('base', 'nSample_Array', nSample_Array);

                % Matrix where there are no masses and with a single ID
                Mat_all = zeros(size(MWMassesArray,1),N_sample*2+2);
                Mw_all = cell(size(MWMassesArray,1)+1,N_sample*2+2);

                % Fill the tables
                Mw_all{1,1} = 'ID';
                Mw_all{1,2} = 'Mw_mean';

                for s = 1:N_sample
                    Mw_all{1,1+s*2} = ['ID_',samples(s).SpectrumName];
                    Mw_all{1,2+s*2} = ['Mw_',samples(s).SpectrumName];
                end

                % Loop until the end of MWMassesArray
                Index = 1;
                Indice_mw_av = 0;
                pos_array = 2;

                % See all the masses in MWMassesArray
                while (Index<length(MWMassesArray))

                    MassesMean(pos_array,1) = MWMassesArray(Index);
                    % Create a vector whose dimension is the number of samples
                    vet = zeros(N_sample,1);
                    vet(1) = 1;

                    for k=1:N_sample-1
                        % Compare a mass with the successive ones
                        % and see if they are distant less than 6 ppm
                        if (Index+k)<length(MWMassesArray) && abs((MassesMean(pos_array)-MWMassesArray(Index+k)).*10^6/MassesMean(pos_array))<=app.ppm_value
                            % See if the number of sample is the same
                            % and if the mass is distant less than 6 ppm
                            % from the one which is 2 positions forward
                            if nSample_Array(Index+k)==nSample_Array(Index+k+1)&& abs((MassesMean(pos_array)-MWMassesArray(Index+k+1)).*10^6/MassesMean(pos_array))<=app.ppm_value

                                Mw_all{pos_array,end}='2 masses';
                                % pos = index of the minimum element
                                [~,pos] = min(abs((MassesMean(pos_array)-MWMassesArray(Index+k:Index+k+1)).*10^6./MassesMean(pos_array)));
                                switch pos
                                    case 1
                                        MassesMean(pos_array) = mean(MWMassesArray(Index:Index+k));
                                    case 2
                                        MassesMean(pos_array) = mean(MWMassesArray(Index:Index+k-1),MWMassesArray(Index:Index+k+1));
                                end

                                if k==n_file-1 && abs((MassesMean(pos_array)-MWMassesArray(Index+n_file)).*10^6/MassesMean(pos_array))<=app.ppm_value
                                    Mw_all{pos_array,end} = 'Likely alignment mistake';
                                    MassesMean(pos_array) = mean(MWMassesArray(Index:Index+k+1));
                                    vet(end+1) = k+2;
                                end

                            else
                                % Mean between the mass and the next one
                                MassesMean(pos_array) = mean(MWMassesArray(Index:Index+k));
                                vet(k+1) = k+1;
                            end
                        end
                    end

                    buffFind = vet(vet>0)+Index-1;
                    Mat_all(pos_array-1,1) = pos_array-1;
                    Mw_all(pos_array,1) = num2cell(pos_array-1);

                    % Calculate mean value of masses
                    % Write in mat_all
                    % New mean mass ID
                    Mat_all(pos_array-1,2) = MassesMean(pos_array);
                    % Old mass ID
                    Mat_all(pos_array-1,nSample_Array(buffFind)*2+1) = IDArray(buffFind);
                    % Intensity associated to the new mean mass
                    Mat_all(pos_array-1,nSample_Array(buffFind)*2+2) = IntensityArray(buffFind);
                    % Write in mw_all
                    Mw_all(pos_array,2) = num2cell(MassesMean(pos_array));
                    Mw_all(pos_array,nSample_Array(buffFind)*2+1) = num2cell(IDArray(buffFind));
                    Mw_all(pos_array,nSample_Array(buffFind)*2+2) = num2cell(MWMassesArray(buffFind));
                    % Update index
                    Index = Index+max(vet);
                    pos_array = pos_array+1;
                    Indice_mw_av = cat(1,Indice_mw_av,buffFind);

                end

                MassesMean(1) = [];
                Indice_mw_av(1) = [];
                % Remove extra rows
                dim_MassesMean = length(MassesMean);
                Mat_all(dim_MassesMean+1:end,:) = [];
                Mw_all(dim_MassesMean+2:end,:) = [];

                if app.check==1

                    if strcmpi(m_acq,'pos')

                        app.WaitingLabel.Text = '...Sorting the matrix by adducts...';

                        % Cell array with the adducts sorted near their MH+
                        Mat_ord = cell(size(MWMassesArray,1),N_sample*2+3);
                        Mat_ord{1,2} = 'ID';
                        Mat_ord{1,3} = 'Mw_mean';

                        for s = 1:N_sample
                            Mat_ord{1,2+s*2} = ['ID_',samples(s).SpectrumName];
                            Mat_ord{1,3+s*2} = ['int_',samples(s).SpectrumName];
                        end

                        % Group masses with this order:(M-H+ M-Na+ M-K+)
                        Mat_ord(dim_MassesMean+2:end,:) = [];
                        Mat_Mw = cell(dim_MassesMean+1,N_sample*2+3);
                        Mat_Mw(1,2:end) = Mw_all(1,:);

                        % First row is for the heading
                        pos_vett = 2;
                        o = 1;

                        % This section finds the adducts of a certain mass
                        % and eventually puts them close to it
                        while (o < length(MassesMean))
                            if MassesMean(o)~=0

                                Mat_ord{pos_vett,1} = tag_M_H;
                                Mat_Mw{pos_vett,1} = tag_M_H;
                                Mat_ord(pos_vett,2:end) = num2cell(Mat_all(o,:));
                                Mat_Mw(pos_vett,2:end) = Mw_all(o+1,:);
                                add_MW = o+1;
                                add_Na = [];
                                add_K = [];
                                mw_thNa = MassesMean(o)-M_H+M_Na;
                                mw_thK = MassesMean(o)-M_H+M_K;
                                while (add_MW<length(MassesMean))
                                    if (abs((mw_thNa-MassesMean(add_MW))*10^6/mw_thNa)<=app.ppm_value)
                                        pos_MW_Na = add_MW;
                                        add_Na = 1;
                                    end

                                    if (abs((mw_thK-MassesMean(add_MW))*10^6/mw_thK)<=app.ppm_value);
                                        pos_MW_K = add_MW;
                                        add_K = 1;
                                    end

                                    if ~isempty(add_Na) || ~isempty(add_K)
                                        add_MW = length(MassesMean);
                                    end
                                    add_MW = add_MW+1;
                                end

                                if~isempty(add_Na)
                                    pos_vett = pos_vett+1;
                                    Mat_ord{pos_vett,1} = 'M+Na';
                                    Mat_ord(pos_vett,2:end) = num2cell(Mat_all(pos_MW_Na,:));
                                    Mat_Mw{pos_vett,1} = 'M+Na';
                                    Mat_Mw(pos_vett,2:end) = Mw_all(pos_MW_Na+1,:);
                                    MassesMean(pos_MW_Na) = [];
                                    Mat_all(pos_MW_Na,:) = [];
                                end

                                if~isempty(add_K)
                                    pos_vett = pos_vett+1;
                                    Mat_ord{pos_vett,1} = 'M+K';
                                    Mat_ord(pos_vett,2:end) = num2cell(Mat_all(pos_MW_K,:));
                                    Mat_Mw{pos_vett,1} = 'M+K';
                                    Mat_Mw(pos_vett,2:end) = Mw_all(pos_MW_K+1,:);
                                    MassesMean(pos_MW_K) = [];
                                    Mat_all(pos_MW_K,:) = [];
                                end

                                o = o+1;
                                pos_vett = pos_vett+1;
                            else
                                MassesMean(o)=[];
                                o = o+1;
                            end
                        end

                        Mat_Simca = Mat_ord(:,[2 3 5:2:(N_sample*2)+3]);
                        file_mat = [path_out,'.mat'];
                        %save(file_mat,'path_out','Mat_ord','Mat_Simca');
                        clear Mat_all
                        clear Mw_all
                        clear Mat_Mw
                        sorted_matrix = 1;
                        %writecell(Mat_Simca, strcat(path_out,'_alignment_data_sorted.xlsx'));

                    else
                        Mat_Simca = Mw_all(:, [1 2 3:2:(N_sample*2+2)]);
                        for s=1:N_sample
                            Mat_Simca{1,2+s} = ['int_',samples(s).SpectrumName];
                        end
                        tmp = Mat_all(:, [4:2:(N_sample*2+2)]);
                        for i=1:height(tmp)
                            for j=1:N_sample
                                tmp_num = tmp(i,j);
                                Mat_Simca{(1+i),(2+j)} = tmp_num;
                            end
                        end

                        file_mat = [path_out, '.mat'];
                        %save(file_mat,'path_out','Mat_Simca')
                        clear Mat_all
                        clear Mw_all
                        sorted_matrix = 0;
                        %writecell(Mat_Simca, strcat(path_out,'_alignment_data.xlsx'));

                    end

                else

                    Mat_Simca = Mw_all(:, [1 2 3:2:(N_sample*2+2)]);
                    for s=1:N_sample
                        Mat_Simca{1,2+s} = ['int_',samples(s).SpectrumName];
                    end
                    tmp = Mat_all(:, [4:2:(N_sample*2+2)]);
                    for i=1:height(tmp)
                        for j=1:N_sample
                            tmp_num = tmp(i,j);
                            Mat_Simca{(1+i),(2+j)} = tmp_num;
                        end
                    end

                    file_mat = [path_out, '.mat'];
                    %save(file_mat,'path_out','Mat_Simca')
                    clear Mat_all
                    clear Mw_all
                    sorted_matrix = 0;
                    %writecell(Mat_Simca, strcat(path_out,'_alignment_data.xlsx'));

                end

                % Discard the spurious peaks and replace the zeros with NaN
                % Count how many peaks correspond to each mass
                tmp_table = cell2table(Mat_Simca, 'VariableNames', Mat_Simca(1,:));
                tmp_table(1,:) = [];
                % Last row is void 
                tmp_table(end,:) = [];
                num_masses_mean = height(tmp_table);
       
                id = cell2mat(tmp_table.ID);
                count_matrix = zeros(length(id), 3);
                save('id');
                save('count_matrix');
                mass = tmp_table(1:end,2);
                save('mass');
                count_matrix(1:end,1) = id;
                count_matrix(1:end,2) = cell2mat(mass.Mw_mean);

                % Build a matrix 
                mat_peaks = tmp_table(1:num_masses_mean,1:2+N_sample);
                mat_peaks = table2array(mat_peaks);
                mat_peaks = cell2mat(mat_peaks);

                j = 1;
                for j=1:num_masses_mean
                    non_zero_pos = find(mat_peaks(j,:)~=0);
                    num_non_zero = length(non_zero_pos)-2;
                    count_matrix(j,3) = num_non_zero;
                end

                peaks_number = app.SamplecutoffEditField.Value;

                % Delete the rows where the count is less tahn peaks_number 
                pos_peak = find(count_matrix(:,3)<=peaks_number);
                if length(pos_peak ~=0)
                    mat_peaks(pos_peak,:) = [];
                    tmp_table(pos_peak,:) = [];
                end

                mat_peaks(mat_peaks==0) = NaN;
                tmp_table{1:end,3:2+N_sample} = num2cell(mat_peaks(1:end,3:2+N_sample));

                name_tok = strtok(file_mat,'.');
                if sorted_matrix==0
                    writetable(tmp_table, strcat(name_tok, '_Alignment_Data.xlsx'));
                else
                    writetable(tmp_table, strcat(name_tok, '_Alignment_Data_Sorted.xlsx'));  
                end

            end

            save(file_mat,'path_out','tmp_table');
            app.WaitingLabel.Text = 'Alignment section - THE END';
        end

        % Button pushed function: HMDBANNOTATIONButton
        function HMDBANNOTATIONButtonPushed(app, event)
            app.WaitingLabel.Text = 'Annotation section - START';

            % Get ppm value from the graphic interface
            app.ppm_value = app.DeltamassppmEditField.Value;
            [name_mat, path_mat] = uigetfile('*.xlsx');
            file_mat = fullfile(path_mat,name_mat);

            w1 = readcell(file_mat);
            mat_SIMCA = w1;
            path_out = path_mat;
            Nmass_HMDB = height(mat_SIMCA)-1;
            % col_index for later 
            col_index = width(mat_SIMCA)+1;
            % Mw_mean: contains the m/z
            Mw_mean = cell2mat(mat_SIMCA(2:end,2));

            % Add columns for the HMDB identification
            mat_SIMCA{1,end+1} = 'HMDB_ID';
            mat_SIMCA{1,end+1} = 'HMDB_Metabolite';
            mat_SIMCA{1,end+1} = 'Adduct';
            mat_SIMCA{1,end+1} = 'DB';

            % Get HMDB (positive or negative)
            [name_HMDB,path_HMDB] = uigetfile('*.mat','HMDB choice');
            db_HMDB = fullfile(path_HMDB,name_HMDB);
            w2 = load(db_HMDB);
            % Load the lists from the HMDB
            HMDBID = w2.HMDBID;
            Metabolite = w2.Metabolite;
            Adduct = w2.Adduct;
            Mw_HMDB = w2.Mw_HMBD;
            DB = w2.MSMS;

            hmdb_id = mat_SIMCA;
            clear mat_SIMCA

            for h=1:Nmass_HMDB

                % Find the positions of the masses closer than ppm_value
                % from the HMDB masses
                ver_HMDB = find(Mw_HMDB>=(Mw_mean(h)-10^-6*app.ppm_value*Mw_mean(h))&Mw_HMDB<=(Mw_mean(h)+10^-6*app.ppm_value*Mw_mean(h)));

                if ~isempty(ver_HMDB)
                    if length(ver_HMDB)==1
                        hmdb_id{h+1,col_index} = HMDBID{ver_HMDB};
                        hmdb_id{h+1,col_index+1} = Metabolite{ver_HMDB};
                        hmdb_id{h+1,col_index+2} = Adduct{ver_HMDB};
                        hmdb_id{h+1,col_index+3} = DB{ver_HMDB};
                    elseif length(ver_HMDB)==2
                        if strcmpi(Metabolite{ver_HMDB(1)},Metabolite{ver_HMDB(2)})
                            hmdb_id{h+1,col_index} = HMDBID{ver_HMDB(2)};
                            hmdb_id{h+1,col_index+1} = Metabolite{ver_HMDB(2)};
                            hmdb_id{h+1,col_index+2} = Adduct{ver_HMDB(2)};
                            hmdb_id{h+1,col_index+3} = DB{ver_HMDB(2)};
                        elseif strcmpi(string(DB{ver_HMDB(1)}),'2')&strcmpi(string(DB{ver_HMDB(2)}),'1')
                            hmdb_id{h+1,col_index} = HMDBID{ver_HMDB(1)};
                            hmdb_id{h+1,col_index+1} = Metabolite{ver_HMDB(1)};
                            hmdb_id{h+1,col_index+2} = Adduct{ver_HMDB(1)};
                            hmdb_id{h+1,col_index+3} = DB{ver_HMDB(1)};
                        elseif strcmpi(string(DB{ver_HMDB(1)}),'1')&strcmpi(string(DB{ver_HMDB(2)}),'2')
                            hmdb_id{h+1,col_index} = HMDBID{ver_HMDB(2)};
                            hmdb_id{h+1,col_index+1} = Metabolite{ver_HMDB(2)};
                            hmdb_id{h+1,col_index+2} = Adduct{ver_HMDB(2)};
                            hmdb_id{h+1,col_index+3} = DB{ver_HMDB(2)};
                        else
                            hmdb_id{h+1,col_index+1} = '2 metabolites';
                        end
                    elseif length(ver_HMDB)>2
                        count_db2 = 0;
                        pos_db2 = [];
                        a = 1;
                        for i=1:length(ver_HMDB)
                            if strcmpi(string(DB{ver_HMDB(i)}),'2')
                                count_db2 = count_db2+1;
                                pos_db2(a) = i;
                                a = a+1;
                            end
                        end
                        if count_db2==0
                            ad = strcat(num2str(length(ver_HMDB)),' metabolites');
                            hmdb_id{h+1,col_index+1} = string(ad);
                        elseif count_db2==1
                            hmdb_id{h+1,col_index} = HMDBID{ver_HMDB(pos_db2)};
                            hmdb_id{h+1,col_index+1} = Metabolite{ver_HMDB(pos_db2)};
                            hmdb_id{h+1,col_index+2} = Adduct{ver_HMDB(pos_db2)};
                            hmdb_id{h+1,col_index+3} = DB{ver_HMDB(pos_db2)};
                        else count_db2>=2
                            ad = strcat(num2str(length(pos_db2)),' metabolites in DB2');
                            hmdb_id{h+1,col_index+1} = string(ad);
                        end
                    end
                end
            end

            name_tok = strtok(file_mat,'.');
            for i = 1:numel(hmdb_id)
                if ismissing(hmdb_id{i})
                    hmdb_id{i} = nan;
                end
            end
            writecell(hmdb_id, strcat(name_tok, '_HMDB_Id.xlsx'));
            app.WaitingLabel.Text = 'Annotation section - THE END';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EASYFIALabel = uilabel(app.UIFigure);
            app.EASYFIALabel.Text = 'EASY-FIA';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.DeltamassppmEditFieldLabel = uilabel(app.ParametersPanel);
            app.DeltamassppmEditFieldLabel.Text = 'Delta mass (ppm)';
            app.DeltamassppmEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DeltamassppmEditField.Value = 6;
            app.IntensitycutoffEditFieldLabel = uilabel(app.ParametersPanel);
            app.IntensitycutoffEditFieldLabel.Text = 'Intensity cut-off';
            app.IntensitycutoffEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SamplecutoffEditFieldLabel = uilabel(app.ParametersPanel);
            app.SamplecutoffEditFieldLabel.Text = 'Sample cut-off ';
            app.SamplecutoffEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SamplecutoffEditField.Value = 1;
            app.AdductdefinitionPanel = uipanel(app.UIFigure);
            app.AdductdefinitionPanel.Title = 'Adduct definition';
            app.MNaLabel = uilabel(app.AdductdefinitionPanel);
            app.MNaLabel.Text = 'M-Na+';
            app.MNaEditField = uieditfield(app.AdductdefinitionPanel, 'numeric');
            app.MNaEditField.ValueDisplayFormat = '%.4f';
            app.MNaEditField.Value = 22.9892;
            app.MKLabel = uilabel(app.AdductdefinitionPanel);
            app.MKLabel.Text = 'M-K+';
            app.MKEditField = uieditfield(app.AdductdefinitionPanel, 'numeric');
            app.MKEditField.ValueDisplayFormat = '%.4f';
            app.MKEditField.Value = 38.9632;
            app.OutputFolderEditFieldLabel = uilabel(app.UIFigure);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder ';
            app.OutputFolderEditField = uieditfield(app.UIFigure, 'text');
            app.ALIGNMENTButton = uibutton(app.UIFigure, 'push');
            app.ALIGNMENTButton.ButtonPushedFcn = createCallbackFcn(app, @ALIGNMENTButtonPushed, true);
            app.ALIGNMENTButton.Text = 'ALIGNMENT ';
            app.HMDBANNOTATIONButton = uibutton(app.UIFigure, 'push');
            app.HMDBANNOTATIONButton.ButtonPushedFcn = createCallbackFcn(app, @HMDBANNOTATIONButtonPushed, true);
            app.HMDBANNOTATIONButton.Text = 'HMDB ANNOTATION';
            app.SortmatrixbyadductsCheckBox = uicheckbox(app.UIFigure);
            app.SortmatrixbyadductsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SortmatrixbyadductsCheckBoxValueChanged, true);
            app.SortmatrixbyadductsCheckBox.Text = 'Sort matrix by adducts ';
            app.WaitingLabel = uilabel(app.UIFigure);
            app.WaitingLabel.Text = '- Waiting - ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '___________________________________________________';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '_____________________________';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EASY_FIA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end